classdef computer_vision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompositeImageCreatorUIFigure  matlab.ui.Figure
        btnBrowse                      matlab.ui.control.Button
        btnBrowseLabel                 matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        numPixelsX                     matlab.ui.control.NumericEditField
        PleaseenterthenumberoftilestocreateEditFieldLabel  matlab.ui.control.Label
        numTiles                       matlab.ui.control.NumericEditField
        compositeImagePanel            matlab.ui.container.Panel
        btnSubmit                      matlab.ui.control.Button
        PleaseselectthetypeofcompositionbuilderDropDownLabel  matlab.ui.control.Label
        compositionBuilder             matlab.ui.control.DropDown
        YLabel                         matlab.ui.control.Label
        numPixelsY                     matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        targetImage % our target image
        tImageH % our target image height
        tImageW % our target image width
        srcDirectory % our composite image library
        srcImageSet % our composite src image set
        rgbAvgValues % RGB Average of composite library
        segmentSize % global segment size
        compositeImage % final image
        tileWidth
        tileHeight
    end

    methods (Access = private)
    
        function updatePixelValue(app)
            
        end
        
        
        function updateSrcDirectory(app)
            app.srcDirectory = uigetdir;
        end
        
        
        function setSrcImagesToMatrix(app)
            app.srcImageSet = imageSet(fullfile(app.srcDirectory));
            
            buildCompositeLibraryRGBValues(app);
        end
        
        
        function segmentTargetImage(app)
            
            dimension = sqrt(app.numTiles.Value);
            
            app.tileWidth = app.numPixelsX.Value/dimension;
            app.tileHeight = app.numPixelsY.Value/dimension;
                                   
            fun = @(block_struct) buildComposite(app,block_struct.data);
            
            newImage = imresize(app.targetImage, [app.numPixelsX.Value, app.numPixelsY.Value]);
            
            app.compositeImage = blockproc(newImage, [app.tileHeight, app.tileWidth], fun);
            
        end
        
        
        function block = buildComposite(app, imblock)
            
            blockValue = mean(imblock);
            
            blockValue = blockValue(:,:,1);
            
            blockValue = blockValue(1);
            
            block = findMatch(app, blockValue);
        
        end
        
        
        function buildCompositeLibraryRGBValues(app)

            % https://www.mathworks.com/matlabcentral/answers/160076-how-to-read-multiple-images-and-calculate-mean
            meanEachImage = arrayfun(@(x) mean(reshape(app.srcImageSet.read(x),[],3)), (1:app.srcImageSet.Count)','UniformOutput',false);
            
            meanImages = [];
            
            for i = 1 : size(meanEachImage)
                meanImages = [meanImages;mean(cell2mat(meanEachImage(i)))];
            end
            
            app.rgbAvgValues = meanImages;
            
        end
        
        
        function match = findMatch(app, blockValue)
            
            % Find index of the best matching rgb value
            [c index] = min(abs(app.rgbAvgValues-blockValue));
            
            val = app.srcImageSet.read(index);
         
            match = imresize(val, [app.tileHeight, app.tileWidth]);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: btnBrowse
        function btnBrowsePushed(app, event)
            [filename, pathname] = uigetfile( ...
                {'*.jpg';'*.png';'*.bmp';'*.*'}, ...
                'Pick an file');
            
            app.targetImage = imread(strcat(pathname, filename));
            
            updatePixelValue(app);
            
        end

        % Button pushed function: btnSubmit
        function btnSubmitPushed(app, event)
            updateSrcDirectory(app);
            
            setSrcImagesToMatrix(app);
            
            segmentTargetImage(app);
            
            % update later to be shown in composite panel
            imshow(app.compositeImage);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompositeImageCreatorUIFigure = uifigure;
            app.CompositeImageCreatorUIFigure.Name = 'Composite Image Creator';
            app.btnBrowse = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnBrowsePushed, true);
            app.btnBrowse.Text = 'Browse...';
            app.btnBrowseLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.btnBrowseLabel.Text = 'Please select your target image:';
            app.XLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.XLabel.Text = 'X';
            app.numPixelsX = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsX.Limits = [1 Inf];
            app.numPixelsX.ValueDisplayFormat = '%.0f';
            app.numPixelsX.Value = 1;
            app.PleaseenterthenumberoftilestocreateEditFieldLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseenterthenumberoftilestocreateEditFieldLabel.Text = 'Please enter the number of tiles to create';
            app.numTiles = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numTiles.Limits = [1 Inf];
            app.numTiles.ValueDisplayFormat = '%.0f';
            app.numTiles.Value = 1;
            app.compositeImagePanel = uipanel(app.CompositeImageCreatorUIFigure);
            app.compositeImagePanel.Title = 'Composite Image';
            app.btnSubmit = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSubmit.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitPushed, true);
            app.btnSubmit.Text = 'Submit';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel.Text = 'Please select the type of composition builder';
            app.compositionBuilder = uidropdown(app.CompositeImageCreatorUIFigure);
            app.compositionBuilder.Items = {'Avg. RGB Values', 'Option 2'};
            app.compositionBuilder.Value = 'Avg. RGB Values';
            app.YLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.YLabel.Text = 'Y';
            app.numPixelsY = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixelsY.Limits = [1 Inf];
            app.numPixelsY.ValueDisplayFormat = '%.0f';
            app.numPixelsY.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = computer_vision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompositeImageCreatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompositeImageCreatorUIFigure)
        end
    end
end